classdef MachAndSpeedOfSound < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SpeedofSoundftsEditField       matlab.ui.control.NumericEditField
        SpeedofSoundftsEditFieldLabel  matlab.ui.control.Label
        MachNumberEditField            matlab.ui.control.NumericEditField
        MachNumberEditFieldLabel       matlab.ui.control.Label
        AltitudeftSpinner              matlab.ui.control.Spinner
        AltitudeLabel                  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        CalculateButton                matlab.ui.control.Button
        AirspeedEditField              matlab.ui.control.NumericEditField
        AirspeedLabel                  matlab.ui.control.Label
        VelocityUnitDropDown           matlab.ui.control.DropDown
        VelocityUnitDropDownLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            altitude = app.AltitudeftSpinner.Value / 1000;
            temperature_data = readtable("https://www.pdas.com/milstd210.html",... % imports temperature data
    FileType="html",ReadVariableNames=true,ThousandsSeparator=",");
            
            % Speed of Sound Calculation
            T = table2array(temperature_data(altitude + 1,6)); % selects temperature value from table
            gamma = 1.4; % heat capacity ratio for air
            R = 1716.46; % universal gas constant
            a = sqrt(gamma*R*T); % Mach 1 airspeed
            
            airspeed = app.AirspeedEditField.Value;
            if app.VelocityUnitDropDown.Value == "Knots"
                airspeed = airspeed * 1.68781;
            end

            app.MachNumberEditField.Value = airspeed/a;
            app.SpeedofSoundftsEditField.Value = a;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VelocityUnitDropDownLabel = uilabel(app.UIFigure);
            app.VelocityUnitDropDownLabel.Text = 'Velocity Unit';
            app.VelocityUnitDropDown = uidropdown(app.UIFigure);
            app.VelocityUnitDropDown.Items = {'Ft/s', 'Knots'};
            app.VelocityUnitDropDown.Value = 'Knots';
            app.AirspeedLabel = uilabel(app.UIFigure);
            app.AirspeedLabel.Text = 'Airspeed';
            app.AirspeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.AirspeedEditField.Limits = [0 Inf];
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'This program calculates the Mach number of an aircraft at an altitude given a known air speed. The table from https://www.pdas.com/milstd210.html is used for temperature data. Altitudes must be entered in feet to the nearest thousandth of a foot.'};
            app.AltitudeLabel = uilabel(app.UIFigure);
            app.AltitudeLabel.Text = 'Altitude (ft)';
            app.AltitudeftSpinner = uispinner(app.UIFigure);
            app.AltitudeftSpinner.Step = 1000;
            app.AltitudeftSpinner.Limits = [0 60000];
            app.AltitudeftSpinner.ValueDisplayFormat = '%.0f';
            app.MachNumberEditFieldLabel = uilabel(app.UIFigure);
            app.MachNumberEditFieldLabel.Text = 'Mach Number';
            app.MachNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.MachNumberEditField.Editable = 'off';
            app.SpeedofSoundftsEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedofSoundftsEditFieldLabel.Text = 'Speed of Sound (ft/s)';
            app.SpeedofSoundftsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedofSoundftsEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MachAndSpeedOfSound

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end